classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField3       matlab.ui.control.NumericEditField
        EditField3Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        TButton          matlab.ui.control.Button
        SButton          matlab.ui.control.Button
        plot             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        userInput % Description
    end
    
    methods (Access = public)
        
        function keyPressCallback(src, event)
            % Use the global variable 'userInput' inside the callback function
            global userInput;
            % Get the pressed key
            key = event.Key;
        
            % Handle the user input
            if (strcmp(key, 's') || strcmp(key, 't'))
                fprintf(userInput)
                userInput = key;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            h1 = animatedline(app.plot, 'Color','b','LineWidth',2, 'MaximumNumPoints',500); 
            h2 = animatedline(app.plot, 'Color','r','LineWidth',2, 'MaximumNumPoints',500); 
            h3 = animatedline(app.plot, 'Color','g','LineWidth',2, 'MaximumNumPoints',500); 
            % grid on;
            
            % setup time vector
            t = linspace(0, 1e4*pi, 100000);
            %setup fake data params
            loadcell = 100*sin(t);
            motor = 60*sin(t+2);
            setPoint = 40*cos(t+1);

            lgd = legend(app.plot, {"loadcell", "setPoint", "motor"});
            
            for k = 1:length(t)
                addpoints(h1, t(k), loadcell(k))
                addpoints(h2, t(k), setPoint(k))
                addpoints(h3, t(k), motor(k))


                % set xlim to move plot horizontally to the right
                % can also set ylim to scale plot upwards and down
                xlim(app.plot, [t(k)-10, t(k)+10])
            
                % draw and sleep
                drawnow
                java.lang.Thread.sleep(50);
            end

            % screen_property = get(0,'screensize');
            % set(gcf, "OuterPosition", [0, screen_property(4)/2, ...
            % screen_property(3)/2, screen_property(4)/2])

            keyPressCallback(app, event)
        end

        % Button down function: plot
        function plotButtonDown(app, event)
            keyPressCallback(app, event)
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            app.userInput = "s";
            fprintf(app.userInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot = uiaxes(app.UIFigure);
            title(app.plot, 'Title')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            zlabel(app.plot, 'Z')
            app.plot.ButtonDownFcn = createCallbackFcn(app, @plotButtonDown, true);
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S Button';
            app.TButton = uibutton(app.UIFigure, 'push');
            app.TButton.Text = 'T Button';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end